classdef LiCongYYDS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup2              matlab.ui.container.TabGroup
        observeV               matlab.ui.container.Tab
        Label                  matlab.ui.control.Label
        Lamp_card              matlab.ui.control.Lamp
        Switch_card            matlab.ui.control.Switch
        Panel_channel2         matlab.ui.container.Panel
        Button_startObserveV2  matlab.ui.control.Button
        rpmLabel_2             matlab.ui.control.Label
        EditField_2Label       matlab.ui.control.Label
        V_channel2             matlab.ui.control.NumericEditField
        Panel_channel1         matlab.ui.container.Panel
        Button_startObserveV1  matlab.ui.control.Button
        rpmLabel               matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        V_channel1             matlab.ui.control.NumericEditField
        Button_isFreze         matlab.ui.control.StateButton
        Panel                  matlab.ui.container.Panel
        Label_4                matlab.ui.control.Label
        samplingRate           matlab.ui.control.NumericEditField
        EditField_2Label_2     matlab.ui.control.Label
        samplingExtent         matlab.ui.control.NumericEditField
        Tab_3                  matlab.ui.container.Tab
        UIAxes_sub             matlab.ui.control.UIAxes
        UIAxes_main            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Fs % 采样频率
        N %采样长度
        T %时间长度
        
        signalChannel1 %采集卡信号
        signalChannel2
        
        
        X %横坐标
        cardState = 0;
        
        xLimit %横坐标范围
        
        cardData % Description
        isFreze % 是否冻结显示
    end

    methods (Access = private)
    
        function show_channel_signal(app)
            get_channel_signal(app)
            xUpper = round(0.01*size(app.X,2));
            plot(app.UIAxes_signal2,app.X(1:xUpper),app.Signal2Channel1.Y(1:xUpper));
            hold(app.UIAxes_signal2,"on");
            plot(app.UIAxes_signal2,app.X(1:xUpper),app.Signal2Channel2.Y(1:xUpper));
            hold(app.UIAxes_signal2,"off");
            plot(app.UIAxes_Channel1,app.X,app.Signal2Channel1.Y);
            plot(app.UIAxes_Channel2,app.X,app.Signal2Channel2.Y);
            if ~app.isFreze
                app.V_channel1.Value = TDZCD(app,app.signalChannel1.Y);
                app.V_channel2.Value = TDZCD(app,app.signalChannel2.Y);

            end
        end


        function get_channel_signal(app)
            refreshT(app)
            app.cardData=USBDAQ_readtwoCH(app.N,app.Fs,1,5);
            app.signalChannel1.Y=app.CardData(:,1);
            app.signalChannel2.Y=app.CardData(:,2);
        end
        
        %% 数据分析
        function f = TDZCD(app,y)
        %Time domain zero crossing detection时域过零检测算法
            counter =0;
            for i=1:app.N-1
                if y(i)*y(i+1)< 0
                    counter = counter+1;
                end
            end
            f = counter/(2*app.T);
        end
        
        %%辅助函数
        function refreshT(app)
            app.T = app.N/app.Fs;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.samplingRate.Value = 5120;
            app.Fs = app.samplingRate.Value;
            app.samplingExtent.Value = 1024;
            app.N = app.samplingExtent.Value;
            refreshT(app);
            app.signalChannel1.Y = ones(1,app.N);
            app.signalChannel2.Y = ones(1,app.N);
            app.Switch_card.ItemsData = {0,1};
            app.Lamp_card.Color = 'red';
        end

        % Value changed function: Button_isFreze
        function Button_isFrezeValueChanged(app, event)
            app.isFreze = app.Button_isFreze.Value;
        end

        % Value changed function: Switch_card
        function Switch_cardValueChanged(app, event)
            value = app.Switch_card.Value;
            
            if (value == 0)
                app.Lamp_card.Color = 'red';
                app.cardState = 0;
                USBDAQ_closecard;
            else
                app.cardState=USBDAQ_opencard;
                if app.cardState==1
                    disp('采集卡开启成功');
                    app.Lamp_card.Color = 'green';
                else
                    disp('采集卡开启失败');
                end
                %循环控制，以达到实时更新
                while(app.cardState)
                    show_channel_signal(app);
                    pause(0.2);
                end
            end
        end

        % Button pushed function: Button_startObserveV1
        function Button_startObserveV1Pushed(app, event)
            app.V_channel1.Value = TDZCD(app,app.signalChannel1.Y);
            app.Button_isFreze.Value = 1;
            app.isFreze = 1;
        end

        % Button pushed function: Button_startObserveV2
        function Button_startObserveV2Pushed(app, event)
            app.V_channel2.Value = TDZCD(app,app.signalChannel2.Y);
            app.Button_isFreze.Value = 1;
            app.isFreze = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.observeV = uitab(app.TabGroup2);
            app.observeV.Title = '转子测速';
            app.Label = uilabel(app.observeV);
            app.Label.Text = '采集卡状态';
            app.Lamp_card = uilamp(app.observeV);
            app.Switch_card = uiswitch(app.observeV, 'slider');
            app.Switch_card.ValueChangedFcn = createCallbackFcn(app, @Switch_cardValueChanged, true);
            app.Panel_channel2 = uipanel(app.observeV);
            app.Panel_channel2.Title = '通道5';
            app.Button_startObserveV2 = uibutton(app.Panel_channel2, 'push');
            app.Button_startObserveV2.ButtonPushedFcn = createCallbackFcn(app, @Button_startObserveV2Pushed, true);
            app.Button_startObserveV2.Text = '测速5';
            app.rpmLabel_2 = uilabel(app.Panel_channel2);
            app.rpmLabel_2.Text = 'rpm';
            app.EditField_2Label = uilabel(app.Panel_channel2);
            app.EditField_2Label.Text = '转速';
            app.V_channel2 = uieditfield(app.Panel_channel2, 'numeric');
            app.V_channel2.Editable = 'off';
            app.Panel_channel1 = uipanel(app.observeV);
            app.Panel_channel1.Title = '通道1';
            app.Button_startObserveV1 = uibutton(app.Panel_channel1, 'push');
            app.Button_startObserveV1.ButtonPushedFcn = createCallbackFcn(app, @Button_startObserveV1Pushed, true);
            app.Button_startObserveV1.Text = '测速1';
            app.rpmLabel = uilabel(app.Panel_channel1);
            app.rpmLabel.Text = 'rpm';
            app.Label_3 = uilabel(app.Panel_channel1);
            app.Label_3.Text = '转速';
            app.V_channel1 = uieditfield(app.Panel_channel1, 'numeric');
            app.V_channel1.Editable = 'off';
            app.Button_isFreze = uibutton(app.observeV, 'state');
            app.Button_isFreze.ValueChangedFcn = createCallbackFcn(app, @Button_isFrezeValueChanged, true);
            app.Button_isFreze.Text = '冻结显示';
            app.Panel = uipanel(app.observeV);
            app.Panel.Title = '采样设置';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '采样频率';
            app.samplingRate = uieditfield(app.Panel, 'numeric');
            app.EditField_2Label_2 = uilabel(app.Panel);
            app.EditField_2Label_2.Text = '采样长度';
            app.samplingExtent = uieditfield(app.Panel, 'numeric');
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '仿真';
            app.UIAxes_sub = uiaxes(app.UIFigure);
            title(app.UIAxes_sub, 'Title')
            xlabel(app.UIAxes_sub, 't')
            ylabel(app.UIAxes_sub, 'A')
            app.UIAxes_main = uiaxes(app.UIFigure);
            title(app.UIAxes_main, 'Title')
            xlabel(app.UIAxes_main, 't')
            ylabel(app.UIAxes_main, 'A')
        end
    end

    methods (Access = public)

        % Construct app
        function app = LiCongYYDS

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end